classdef Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputAndTriangular              matlab.ui.control.UIAxes
        ComparationTime                 matlab.ui.control.UIAxes
        ComparationFrequency            matlab.ui.control.UIAxes
        PWMFiltered                     matlab.ui.control.UIAxes
        CalculateButton                 matlab.ui.control.Button
        InputsignalfrequencyHzEditFieldLabel  matlab.ui.control.Label
        InputsignalfrequencyHzEditField  matlab.ui.control.NumericEditField
        TriangularfrequencyHzEditFieldLabel  matlab.ui.control.Label
        TriangularfrequencyHzEditField  matlab.ui.control.NumericEditField
        InputsignalamplitudeVEditFieldLabel  matlab.ui.control.Label
        InputsignalamplitudeVEditField  matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        RLabel                          matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        LHEditFieldLabel                matlab.ui.control.Label
        LHEditField                     matlab.ui.control.NumericEditField
        CFEditFieldLabel                matlab.ui.control.Label
        CFEditField                     matlab.ui.control.NumericEditField
        SeframaCORPLabel                matlab.ui.control.Label
        InputvaluesLabel                matlab.ui.control.Label
        ValuesoftheRLCfilterLabel       matlab.ui.control.Label
        OutputSignal                    matlab.ui.control.UIAxes
        PWMAndFilter                    matlab.ui.control.UIAxes
        InputsignalButtonGroup          matlab.ui.container.ButtonGroup
        SinusoidButton                  matlab.ui.control.RadioButton
        PulsetrainButton                matlab.ui.control.RadioButton
        TriangularButton                matlab.ui.control.RadioButton
        PulseTHighEditFieldLabel        matlab.ui.control.Label
        PulseTHighEditField             matlab.ui.control.NumericEditField
        RefreshButton                   matlab.ui.control.Button
        PressheretoLabel_2              matlab.ui.control.Label
        AutomaticvaluesCheckBox         matlab.ui.control.CheckBox
        fTriperiodsSlider               matlab.ui.control.Slider
        InputsignalLabel                matlab.ui.control.Label
        PWMperiodsSlider                matlab.ui.control.Slider
        PWMLabel                        matlab.ui.control.Label
        PeriodsLabel                    matlab.ui.control.Label
        PeriodsLabel_2                  matlab.ui.control.Label
        fcofthefilterkHzTextAreaLabel   matlab.ui.control.Label
        fcofthefilterkHzTextArea        matlab.ui.control.TextArea
        WiththisRLCvaluesLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        fs = 4410000;           % Sample Rate.
        duration = 0.01;        % Time duration (in seconds).
    end
    

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Initialize time vector
            t = 0:(1/app.fs):app.duration; % Axis time.
            
            %First we get all the values from the fields:
            fIn = app.InputsignalfrequencyHzEditField.Value;
            fTri = app.TriangularfrequencyHzEditField.Value;
            a = app.InputsignalamplitudeVEditField.Value;
            gain = app.GainEditField.Value;
            r = app.REditField.Value;
            l = app.LHEditField.Value;
            c = app.CFEditField.Value;
            Th = app.PulseTHighEditField.Value;
            Th = Th/100;
            
            %Then calculate all the input values depending on the type of signal
            if(app.SinusoidButton.Value == 1)
                 signalIn = a*sin(2*pi*fIn*t);
            end
            if(app.TriangularButton.Value == 1)
                 signalIn = a*triangular(fIn, app.duration);
            end
            if(app.PulsetrainButton.Value == 1)
                 signalIn = pulseTrain(Th, fIn, a);
                 signalIn = (signalIn * 2)-1;
            end
           
            %Triangular input signal
            triIn = triangular(fTri, app.duration);
            
            %Plot input values
            plot(app.InputAndTriangular, t, signalIn);
            hold(app.InputAndTriangular, 'on');
            plot(app.InputAndTriangular, t, triIn);
            hold(app.InputAndTriangular, 'off');
            app.InputAndTriangular.XLim = [0 app.fTriperiodsSlider.Value/fIn];
            app.InputAndTriangular.YLim = [min(signalIn) max(signalIn)];
            
            %We do the comparation and apply the gain
            comparationResult = pwm(signalIn, triIn);
            comparationResult = gain * comparationResult;
            
            %Plot the PWM (in time domain)
            plot(app.ComparationTime, t, comparationResult);
            app.ComparationTime.XLim = [0 20/fTri];
            
            %The fourier transform of the PWM will be:
            comparationResult_Tf = abs(fft(comparationResult)); 
            k = 1:length(comparationResult_Tf);                         % F=f/fs, F=k/N
            f= k*app.fs/length(k);
            
            %Plot the PWM (in frequency domain)
            plot(app.ComparationFrequency, f, comparationResult_Tf);
            app.ComparationFrequency.XLim = [0 5*fTri];                 %5 fundamental periods of PWM
            
            %The transfer function of the filter will be
            numTF = 1/(l*c);
            denTF = abs((1/(l*c)-(2*pi*f).*(2*pi*f))+1i*2*pi*f/(c*r));
            transferFunction =numTF./denTF;

            %Plot the filter with the PWM (in frequency domain),
            %to see what frequencies are going to be filtered.            
            comparationResult_TF = comparationResult_Tf/length(t);      %Normalize
            plot(app.PWMAndFilter, f, comparationResult_TF);
            app.PWMAndFilter.XLim = [0 app.PWMperiodsSlider.Value*fTri];   
            hold(app.PWMAndFilter, 'on');
            plot(app.PWMAndFilter, f, transferFunction);
            hold(app.PWMAndFilter, 'off');

            %num y den to Tz with bilinear transformation
            num = 1;
            den = [l*c, l/r, 1];
           
            Ts = 1/app.fs;
            a1 = 1;
            numZ = [a1 2*a1 a1];

            A = 1-2*l/(r*Ts)+4*l*c/(Ts*Ts);
            B = 2-8*l*c/(Ts*Ts);
            C = 1+2*l/(r*Ts)+4*l*c/(Ts*Ts);
            denZ = [C B A];
            
            %Filter
            filtered = filter (numZ, denZ, comparationResult);
            
            %Filtered signal and output sinusoid
            plot(app.PWMFiltered, f, abs(fft(filtered)));
            app.PWMFiltered.XLim = [0 5*fTri]; 
            plot(app.OutputSignal, t, filtered)
            app.OutputSignal.XLim = [0 5/fIn];
            
            %fc of the filter 
            s = (- 1/(c*r)^2 + 2/(l*c) + ((1/(c*r)^2 - 2/(l*c))^2 + 4/(l*c)^2)^(1/2) ) / 2;
            solution = s^(1/2) / (2*pi);
            
            app.fcofthefilterkHzTextArea.Value = sprintf('%.2s' , solution/1000);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            if(app.SinusoidButton.Value == 1)
                app.PulseTHighEditField.Visible = 'off';
                app.PulseTHighEditFieldLabel.Visible = 'off';
            end
            if(app.TriangularButton.Value == 1)
                 app.PulseTHighEditField.Visible = 'off';
                 app.PulseTHighEditFieldLabel.Visible = 'off';
            end
            if(app.PulsetrainButton.Value == 1)
                app.PulseTHighEditField.Visible = 'on';
                app.PulseTHighEditFieldLabel.Visible = 'on';
            end

        end

        % Value changed function: AutomaticvaluesCheckBox
        function AutomaticvaluesCheckBoxValueChanged(app, event)
            value = app.AutomaticvaluesCheckBox.Value;
            if (value == 0) 
                app.REditField.Editable = 'on';
                app.LHEditField.Editable = 'on';
                app.CFEditField.Editable = 'on';
            end
            if (value == 1) 
                app.REditField.Editable = 'off';
                app.LHEditField.Editable = 'off';
                app.CFEditField.Editable = 'off';
                app.CFEditField.Value = 4.7e-6;
                app.REditField.Value = 1;
                app.LHEditField.Value = 2.2e-5;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputAndTriangular = uiaxes(app.UIFigure);
            title(app.InputAndTriangular, 'Input sinusoid and triangular signal')
            xlabel(app.InputAndTriangular, 'Time [s]')
            ylabel(app.InputAndTriangular, 'Amplitude [V]')
            app.InputAndTriangular.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ComparationTime = uiaxes(app.UIFigure);
            title(app.ComparationTime, 'Comparation result')
            xlabel(app.ComparationTime, 'Time [s]')
            ylabel(app.ComparationTime, 'PWM(t)')
            app.ComparationFrequency = uiaxes(app.UIFigure);
            title(app.ComparationFrequency, 'Comparation result')
            xlabel(app.ComparationFrequency, 'Frequency [Hz]')
            ylabel(app.ComparationFrequency, '|PWM(f)|')
            app.PWMFiltered = uiaxes(app.UIFigure);
            title(app.PWMFiltered, 'PWM filtered')
            xlabel(app.PWMFiltered, 'Frequency [Hz]')
            ylabel(app.PWMFiltered, '|PWM filtered(f)|')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InputsignalfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.InputsignalfrequencyHzEditFieldLabel.Text = 'Input signal frequency [Hz]';
            app.InputsignalfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsignalfrequencyHzEditField.Value = 3000;
            app.TriangularfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.TriangularfrequencyHzEditFieldLabel.Text = 'Triangular frequency [Hz]';
            app.TriangularfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TriangularfrequencyHzEditField.Value = 150000;
            app.InputsignalamplitudeVEditFieldLabel = uilabel(app.UIFigure);
            app.InputsignalamplitudeVEditFieldLabel.Text = 'Input signal amplitude [V]';
            app.InputsignalamplitudeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsignalamplitudeVEditField.Value = 1;
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.Value = 1;
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R[Ω]';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Editable = 'off';
            app.REditField.Value = 1;
            app.LHEditFieldLabel = uilabel(app.UIFigure);
            app.LHEditFieldLabel.Text = 'L[H]';
            app.LHEditField = uieditfield(app.UIFigure, 'numeric');
            app.LHEditField.Editable = 'off';
            app.LHEditField.Value = 2.2e-05;
            app.CFEditFieldLabel = uilabel(app.UIFigure);
            app.CFEditFieldLabel.Text = 'C[F]';
            app.CFEditField = uieditfield(app.UIFigure, 'numeric');
            app.CFEditField.Editable = 'off';
            app.CFEditField.Value = 4.7e-06;
            app.SeframaCORPLabel = uilabel(app.UIFigure);
            app.SeframaCORPLabel.Text = 'Seframa CORP';
            app.InputvaluesLabel = uilabel(app.UIFigure);
            app.InputvaluesLabel.Text = 'Input values:';
            app.ValuesoftheRLCfilterLabel = uilabel(app.UIFigure);
            app.ValuesoftheRLCfilterLabel.Text = 'Values of the RLC filter:';
            app.OutputSignal = uiaxes(app.UIFigure);
            title(app.OutputSignal, 'Output Signal')
            xlabel(app.OutputSignal, 'Time [s]')
            ylabel(app.OutputSignal, 'Amplitude [V]')
            app.PWMAndFilter = uiaxes(app.UIFigure);
            title(app.PWMAndFilter, 'Comparation result with filter')
            xlabel(app.PWMAndFilter, 'Frequency [Hz]')
            ylabel(app.PWMAndFilter, '|Comparation Result(f)|')
            app.InputsignalButtonGroup = uibuttongroup(app.UIFigure);
            app.InputsignalButtonGroup.Title = 'Input signal';
            app.SinusoidButton = uiradiobutton(app.InputsignalButtonGroup);
            app.SinusoidButton.Text = 'Sinusoid';
            app.SinusoidButton.Value = true;
            app.PulsetrainButton = uiradiobutton(app.InputsignalButtonGroup);
            app.PulsetrainButton.Text = 'Pulse train';
            app.TriangularButton = uiradiobutton(app.InputsignalButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.PulseTHighEditFieldLabel = uilabel(app.UIFigure);
            app.PulseTHighEditFieldLabel.Visible = 'off';
            app.PulseTHighEditFieldLabel.Text = 'Pulse -> THigh[%]';
            app.PulseTHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseTHighEditField.Visible = 'off';
            app.PulseTHighEditField.Value = 50;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PressheretoLabel_2 = uilabel(app.UIFigure);
            app.PressheretoLabel_2.Text = 'Press here to:';
            app.AutomaticvaluesCheckBox = uicheckbox(app.UIFigure);
            app.AutomaticvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticvaluesCheckBoxValueChanged, true);
            app.AutomaticvaluesCheckBox.Text = 'Automatic values ';
            app.AutomaticvaluesCheckBox.Value = true;
            app.fTriperiodsSlider = uislider(app.UIFigure);
            app.fTriperiodsSlider.Limits = [0 2];
            app.fTriperiodsSlider.Value = 1;
            app.InputsignalLabel = uilabel(app.UIFigure);
            app.InputsignalLabel.Text = 'Input signal';
            app.PWMperiodsSlider = uislider(app.UIFigure);
            app.PWMperiodsSlider.Limits = [0 10];
            app.PWMperiodsSlider.Value = 3;
            app.PWMLabel = uilabel(app.UIFigure);
            app.PWMLabel.Text = 'PWM';
            app.PeriodsLabel = uilabel(app.UIFigure);
            app.PeriodsLabel.Text = 'Periods';
            app.PeriodsLabel_2 = uilabel(app.UIFigure);
            app.PeriodsLabel_2.Text = 'Periods';
            app.fcofthefilterkHzTextAreaLabel = uilabel(app.UIFigure);
            app.fcofthefilterkHzTextAreaLabel.Text = 'fc of the filter [kHz]';
            app.fcofthefilterkHzTextArea = uitextarea(app.UIFigure);
            app.WiththisRLCvaluesLabel = uilabel(app.UIFigure);
            app.WiththisRLCvaluesLabel.Text = 'With this RLC values:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end